classdef Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EpilepsyDetectionLabel          matlab.ui.control.Label
        NetworkSelectionPanel           matlab.ui.container.Panel
        SelectpredefinednetworkLabel    matlab.ui.control.Label
        DropDownPreDefined              matlab.ui.control.DropDown
        TestButtonPreDefined            matlab.ui.control.Button
        CreateownnetworkLabel           matlab.ui.control.Label
        DropDownType                    matlab.ui.control.DropDown
        SelectnetworktypeLabel          matlab.ui.control.Label
        DropDownTrainingSet             matlab.ui.control.DropDown
        SelecttrainingsetLabel          matlab.ui.control.Label
        DropDownPreference              matlab.ui.control.DropDown
        SelectpreferenceLabel           matlab.ui.control.Label
        TestButtonOwn                   matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SpecializationLabel             matlab.ui.control.Label
        DropDownSpecialization          matlab.ui.control.DropDown
        EvaluationPanel                 matlab.ui.container.Panel
        TruePositivesEditFieldLabel     matlab.ui.control.Label
        TruePositivesEditField          matlab.ui.control.EditField
        TrueNegativesEditFieldLabel     matlab.ui.control.Label
        TrueNegativesEditField          matlab.ui.control.EditField
        FalsePositivesEditFieldLabel    matlab.ui.control.Label
        FalsePositivesEditField         matlab.ui.control.EditField
        SpecificityRecallNoSeizEditFieldLabel  matlab.ui.control.Label
        SpecificityRecallNoSeizEditField  matlab.ui.control.EditField
        SensitivityRecallSeizuresEditFieldLabel  matlab.ui.control.Label
        SensitivityRecallSeizuresEditField  matlab.ui.control.EditField
        PrecisionSeizureEditFieldLabel  matlab.ui.control.Label
        PrecisionSeizureEditField       matlab.ui.control.EditField
        ResultsLabel                    matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        PostprocessingLabel             matlab.ui.control.Label
        DropDownPostProcessing          matlab.ui.control.DropDown
        FalseNegativesEditField_2Label  matlab.ui.control.Label
        FalseNegativesEditField         matlab.ui.control.EditField
        PrecisionNoSeizureEditFieldLabel  matlab.ui.control.Label
        PrecisionNoSeizureEditField     matlab.ui.control.EditField
        PredictionDetectionLabel        matlab.ui.control.Label
    end


    methods (Access = private)
    
        function analyzeResults(app, predictionBoost, detectionBoost)
            global testSet;
            global testTargetMatrix;
            
            global net;
            
            testResults = sim(net,testSet);

            %specialize for prediction
            testResults(2,:) = testResults(2,:) * predictionBoost;
            
            %specialize for detection
            testResults(3,:) = testResults(3,:) * detectionBoost;
            
            for i=1:length(testResults)
                max = 1;
                for j=2:4
                    if(testResults(j,i)>=testResults(max,i))
                       max=j; 
                    end
                end
                testResults(:,i) = zeros(4,1);
                testResults(max,i)=1;
            end
            
            %post-processing
            postProc = app.DropDownPostProcessing.Value;
            if(strcmp(postProc, '10 relaxed'))
                %checks if following 5 out of the next 10 are the same as the actual
                %if not -> inter-ictal (as it is just positive and negative -> no difference)
                
                for i=1:length(testResults)-10
                    followingAreTheSame = 0;
                     for j=1:10
                         if(isequal(testResults(:,i),testResults(:,i+j)))
                             followingAreTheSame = followingAreTheSame+1;
                         end
                     end
                     
                     if(followingAreTheSame < 5)
                         testResults(:,i)=[1;0;0;0];
                     end
                 end
            else
                if(strcmp(postProc, 'None'))
                    
                else
                    %checks if following 5 or 10 are the same as the actual
                    %if all pre-ictal -> keep as pre-ictal
                    %if all ictal -> keep as ictal
                    %if following not the same -> inter-ictal (as it is just positive and negative -> no difference)
                    
                    checkFollowing = str2num(postProc(1:2));
                    for i=1:length(testResults)-checkFollowing
                        followingAreTheSame = 1;
                         for j=1:checkFollowing
                             if(isequal(testResults(:,i),testResults(:,i+j)))
                                 
                             else
                                 followingAreTheSame = 0;
                             end
                         end
                         
                         if(followingAreTheSame == 0)
                             testResults(:,i)=[1;0;0;0];
                         end
                     end 
                end                                   
            end            
                
            
            tpPrediction = 0;
            tnPrediction = 0;
            fnPrediction = 0;
            fpPrediction = 0;
            
            tpDetection = 0;
            tnDetection = 0;
            fnDetection = 0;
            fpDetection = 0;            
                        
            for i=1:length(testResults)
                %prediction
                if(testResults(2,i)==testTargetMatrix(2,i))
                   if(testResults(2,i)==1)
                       tpPrediction = tpPrediction+1;
                   else
                       tnPrediction = tnPrediction+1;
                   end
                else
                   if(testResults(2,i)==1)
                       fpPrediction = fpPrediction+1;
                   else
                       fnPrediction = fnPrediction+1;
                   end
                end
            
                %detection
                if(testResults(3,i)==testTargetMatrix(3,i))
                   if(testResults(3,i)==1)
                       tpDetection = tpDetection+1;
                   else
                       tnDetection = tnDetection+1;
                   end
                else
                   if(testResults(3,i)==1)
                       fpDetection = fpDetection+1;
                   else
                       fnDetection = fnDetection+1;
                   end
                end
            end
            
            %sensitivity - how many true seizures did it predict  - should be high
            %               if we have a seizure, how sure can we be to detect it
            %               it is really bad if fn occur (a real seizure would not be
            %               detected)
            %recall of seizures...
            sensPrediction = tpPrediction/(tpPrediction+fnPrediction);
            sensDetection  = tpDetection /(tpDetection +fnDetection);
            
            %specificity - how many no seizures did it predict
            %               if we do not have a seizure, how sure can we be that we
            %               will not predict a seizure
            %               it is not bad if we have false alarms, but it should not be
            %               too bad
            %recall for no seizures
            specPrediction = tnPrediction/(tnPrediction+fpPrediction);
            specDetection  = tnDetection /(tnDetection +fpDetection);
            
            %precision of seizures - how many of the predicted seizures are seizures
            %               can be seen as false alarm probability
            %               does not need to be very high, but should not be very low
            precPrediction = tpPrediction/(tpPrediction+fpPrediction);
            precDetection  = tpDetection/ (tpDetection +fpDetection);
            
            %precision of no seizure - should be high
            %               how sure can we be that we have really no seizure if we
            %               predict no seizure
            precPredictionNoSeizure = tnPrediction/(tnPrediction+fnPrediction);
            precDetectionNoSeizure  = tnDetection/ (tnDetection +fnDetection);
            
            app.TruePositivesEditField.Value = strcat(num2str(tpPrediction),' | ',num2str(tpDetection));
            app.FalsePositivesEditField.Value = strcat(num2str(fpPrediction),' | ',num2str(fpDetection));
            app.TrueNegativesEditField.Value = strcat(num2str(tnPrediction),' | ',num2str(tnDetection));
            app.FalseNegativesEditField.Value = strcat(num2str(fnPrediction),' | ',num2str(fnDetection));
            
            app.SpecificityRecallNoSeizEditField.Value = strcat(num2str(specPrediction),' | ',num2str(specDetection));
            app.SensitivityRecallSeizuresEditField.Value = strcat(num2str(sensPrediction),' | ',num2str(sensDetection));
            app.PrecisionSeizureEditField.Value = strcat(num2str(precPrediction),' | ',num2str(precDetection));
            app.PrecisionNoSeizureEditField.Value = strcat(num2str(precPredictionNoSeizure),' | ',num2str(precDetectionNoSeizure));
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global trainingSet;
            global trainingSet2;
            global trainingSet3;
            global trainingTargetMatrix;
            global trainingTargetMatrix2;
            global trainingTargetMatrix3;
            
            global testSet;
            global testTargetMatrix;
            
            global net;
            
            load('G4/112502.mat');
            createTargetMatrix;
            generateSets;
            
        end

        % Button pushed function: TestButtonPreDefined
        function TestButtonPreDefinedPushed(app, event)
            global net;
            predictionBoost = 1;
            detectionBoost = 1;
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-Forward Backprob'))
                net = load('network1.mat');
                net = net.network1;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-Forward Backprob (prediction)'))
                net = load('network1.mat');
                net = net.network1;
                predictionBoost = 1.75;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-Forward Backprob (detection)'))
                net = load('network1.mat');
                net = net.network1;
                detectionBoost = 7.75;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Patternnet'))
                net = load('patternnet.mat');
                net = net.patternnet;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Patternnet (prediction)'))
                net = load('patternnet.mat');
                net = net.patternnet;
                predictionBoost = 1.865;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Patternnet (detection)'))
                net = load('patternnet.mat');
                net = net.patternnet;
                detectionBoost = 9;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-forward distributed time delay'))
                net = load('network2.mat');
                net = net.network2;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-forward distributed time delay (prediction)'))
                net = load('network2.mat');
                net = net.network2;
                predictionBoost = 1.69;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-forward distributed time delay (detection)'))
                net = load('network2.mat');
                net = net.network2;
                detectionBoost = 7.79;
            end
                        
            if(strcmp(app.DropDownPreDefined.Value,'Feed-forward distributed time delay (detection no seizures)'))
                net = load('network2.mat');
                net = net.network2;
                detectionBoost = 7.69;
            end
            
            analyzeResults(app, predictionBoost, detectionBoost);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.EpilepsyDetectionLabel = uilabel(app.UIFigure);
            app.EpilepsyDetectionLabel.Text = 'Epilepsy Detection';
            app.NetworkSelectionPanel = uipanel(app.UIFigure);
            app.NetworkSelectionPanel.Title = 'Network Selection';
            app.SelectpredefinednetworkLabel = uilabel(app.NetworkSelectionPanel);
            app.SelectpredefinednetworkLabel.Text = 'Select predefined network';
            app.DropDownPreDefined = uidropdown(app.NetworkSelectionPanel);
            app.DropDownPreDefined.Items = {'Feed-Forward Backprob', 'Feed-Forward Backprob (prediction)', 'Feed-Forward Backprob (detection)', 'Patternnet', 'Patternnet (prediction)', 'Patternnet (detection)', 'Feed-forward distributed time delay', 'Feed-forward distributed time delay (prediction)', 'Feed-forward distributed time delay (detection)', 'Feed-forward distributed time delay (detection no seizures)'};
            app.DropDownPreDefined.Value = 'Feed-Forward Backprob';
            app.TestButtonPreDefined = uibutton(app.NetworkSelectionPanel, 'push');
            app.TestButtonPreDefined.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPreDefinedPushed, true);
            app.TestButtonPreDefined.Text = 'Test';
            app.CreateownnetworkLabel = uilabel(app.NetworkSelectionPanel);
            app.CreateownnetworkLabel.Text = 'Create own network';
            app.DropDownType = uidropdown(app.NetworkSelectionPanel);
            app.DropDownType.Items = {'Feedworward', 'Recurrent'};
            app.DropDownType.Value = 'Feedworward';
            app.SelectnetworktypeLabel = uilabel(app.NetworkSelectionPanel);
            app.SelectnetworktypeLabel.Text = 'Select network type';
            app.DropDownTrainingSet = uidropdown(app.NetworkSelectionPanel);
            app.DropDownTrainingSet.Items = {'Balanced', 'Unbalanced', 'Few Seizures'};
            app.DropDownTrainingSet.Value = 'Balanced';
            app.SelecttrainingsetLabel = uilabel(app.NetworkSelectionPanel);
            app.SelecttrainingsetLabel.Text = 'Select training set';
            app.DropDownPreference = uidropdown(app.NetworkSelectionPanel);
            app.DropDownPreference.Items = {'Prediction', 'Detection'};
            app.DropDownPreference.Value = 'Prediction';
            app.SelectpreferenceLabel = uilabel(app.NetworkSelectionPanel);
            app.SelectpreferenceLabel.Text = 'Select preference';
            app.TestButtonOwn = uibutton(app.NetworkSelectionPanel, 'push');
            app.TestButtonOwn.Text = 'Test';
            app.Label = uilabel(app.NetworkSelectionPanel);
            app.Label.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _';
            app.SpecializationLabel = uilabel(app.NetworkSelectionPanel);
            app.SpecializationLabel.Text = 'Specialization';
            app.DropDownSpecialization = uidropdown(app.NetworkSelectionPanel);
            app.DropDownSpecialization.Items = {'High', 'Medium', 'None'};
            app.DropDownSpecialization.Value = 'High';
            app.EvaluationPanel = uipanel(app.UIFigure);
            app.EvaluationPanel.Title = 'Evaluation';
            app.TruePositivesEditFieldLabel = uilabel(app.EvaluationPanel);
            app.TruePositivesEditFieldLabel.Text = 'True Positives';
            app.TruePositivesEditField = uieditfield(app.EvaluationPanel, 'text');
            app.TrueNegativesEditFieldLabel = uilabel(app.EvaluationPanel);
            app.TrueNegativesEditFieldLabel.Text = 'True Negatives';
            app.TrueNegativesEditField = uieditfield(app.EvaluationPanel, 'text');
            app.FalsePositivesEditFieldLabel = uilabel(app.EvaluationPanel);
            app.FalsePositivesEditFieldLabel.Text = 'False Positives';
            app.FalsePositivesEditField = uieditfield(app.EvaluationPanel, 'text');
            app.SpecificityRecallNoSeizEditFieldLabel = uilabel(app.EvaluationPanel);
            app.SpecificityRecallNoSeizEditFieldLabel.Text = 'Specificity (Recall NoSeiz.)';
            app.SpecificityRecallNoSeizEditField = uieditfield(app.EvaluationPanel, 'text');
            app.SensitivityRecallSeizuresEditFieldLabel = uilabel(app.EvaluationPanel);
            app.SensitivityRecallSeizuresEditFieldLabel.Text = 'Sensitivity (Recall Seizures)';
            app.SensitivityRecallSeizuresEditField = uieditfield(app.EvaluationPanel, 'text');
            app.PrecisionSeizureEditFieldLabel = uilabel(app.EvaluationPanel);
            app.PrecisionSeizureEditFieldLabel.Text = 'Precision (Seizure)';
            app.PrecisionSeizureEditField = uieditfield(app.EvaluationPanel, 'text');
            app.ResultsLabel = uilabel(app.EvaluationPanel);
            app.ResultsLabel.Text = 'Results';
            app.Label_2 = uilabel(app.EvaluationPanel);
            app.Label_2.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ ';
            app.PostprocessingLabel = uilabel(app.EvaluationPanel);
            app.PostprocessingLabel.Text = 'Postprocessing';
            app.DropDownPostProcessing = uidropdown(app.EvaluationPanel);
            app.DropDownPostProcessing.Items = {'None', '5 strict', '10 strict', '15 strict', '10 relaxed'};
            app.DropDownPostProcessing.Value = 'None';
            app.FalseNegativesEditField_2Label = uilabel(app.EvaluationPanel);
            app.FalseNegativesEditField_2Label.Text = 'False Negatives';
            app.FalseNegativesEditField = uieditfield(app.EvaluationPanel, 'text');
            app.PrecisionNoSeizureEditFieldLabel = uilabel(app.EvaluationPanel);
            app.PrecisionNoSeizureEditFieldLabel.Text = 'Precision (No Seizure)';
            app.PrecisionNoSeizureEditField = uieditfield(app.EvaluationPanel, 'text');
            app.PredictionDetectionLabel = uilabel(app.EvaluationPanel);
            app.PredictionDetectionLabel.Text = 'Prediction | Detection';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Gui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end