classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure           % UI Figure
        Label          matlab.ui.control.Label    % Neural Network Test Coc...
        LabelDropDown  matlab.ui.control.Label    % Drop Down
        DropDown       matlab.ui.control.DropDown % Combined Training Set, ...
        Label2         matlab.ui.control.Label    % Select Training Set
        LabelDropDown2 matlab.ui.control.Label    % Drop Down
        DropDown2      matlab.ui.control.DropDown % Associative Memory + Cl...
        Label3         matlab.ui.control.Label    % Select Approach
        Label4         matlab.ui.control.Label    % Select Activation Function
        Label5         matlab.ui.control.Label    % Drop Down
        DropDown3      matlab.ui.control.DropDown % Hardlim, Purelin, Logsig
        Button         matlab.ui.control.Button   % Train
        LabelTextArea  matlab.ui.control.Label    % Test Results (Good Test...
        TextArea       matlab.ui.control.TextArea
        Button2        matlab.ui.control.Button   % Test
        Label8         matlab.ui.control.Label    % Test Results (Bad Testset)
        TextArea2      matlab.ui.control.TextArea
        Label9         matlab.ui.control.Label    % Test Results (Own Testset)
        TextArea3      matlab.ui.control.TextArea
        Button3        matlab.ui.control.Button   % Create Testset
        Panel          matlab.ui.container.Panel  % Training
        Panel2         matlab.ui.container.Panel  % Testset Creation
        Panel3         matlab.ui.container.Panel  % Testing
        Button4        matlab.ui.control.Button   % Show Testset
        TextArea4      matlab.ui.control.TextArea % Your own testset 
    end

    methods (Access = private)
        function  createNetwork(app,trainSet,approach,activationFunction)
           
           data = load('dataset.mat');
           dataset = data.dataset;
           
           load('dataset2.mat');
           load('datasetTarget.mat');
           load('Target.mat');
           load('bigDataSet.mat');
           load('T.mat');
           
           divideSampleSize = 500;
                      
           if(strcmp(trainSet,'Bad Training Set'))
               x = dataset;
               t = datasetTarget;
           end
  
           if(strcmp(trainSet,'Good Training Set'))
               x = dataset2;
               t = datasetTarget;
           end
           
           if(strcmp(trainSet,'Combined Training Set'))
               x = bigDataSet;
               t = Target;
              divideSampleSize = 1000;
           end
           
            global net;            
           net = perceptron;

            net.performParam.lr = 0.5;
            net.trainParam.epochs = 1000;
            net.trainParam.show = 35;
            net.trainParam.goal = 1e-6;
            net.performFcn = 'mse';
            
            if(strcmp(activationFunction,'Purelin'))
                net.layers{1}.transferFcn = 'purelin';
                net.trainFcn = 'traingd';
           end
           
           if(strcmp(activationFunction,'Logsig'))
                net.layers{1}.transferFcn = 'logsig';
                net.trainFcn = 'traingd';
           end
           
           if(strcmp(activationFunction,'Hardlim'))
                net.layers{1}.transferFcn = 'hardlim';
                net.trainFcn = 'trainc';
           end
           
           [trainInd,valInd,testInd] = divideblock(divideSampleSize,0.85,0.15,0);
            net.divideFcn = 'divideblock';
            
            
            if(strcmp(approach,'Associative Memory + Classifier'))

                if(strcmp(trainSet,'Combined Training Set'))
                    WP = T * pinv(x);
                else
                    WP = T(:,1:500) * pinv(x);
                end 
                
                x = WP * x;    
            end
                        
            net = train(net,x,t);
            view(net);
        end
        
        function analyzeResults(app,print,res)
            testResults = res;
            
            classifiedResults = [];
            for i = 1:50
                max = 0;
                for j = 1:10
                   if testResults(j,i) > max
                    max = testResults(j,i);
                   end
                end
                
                for j = 1:10
                   if(testResults(j,i)== max)
                       classifiedResults = [classifiedResults,j];
                       break;
                   end
                end
            end
            
            trueDigits=0;
            for i = 1:50
               should = mod(i,10);
               if should == mod(classifiedResults(i),10)
                   trueDigits = trueDigits + 1;
               end
            end
            if(print==1)
                app.TextArea.Value = strcat(num2str(trueDigits),' out of 50 correct');
            elseif(print == 2)
                app.TextArea2.Value = strcat(num2str(trueDigits),' out of 50 correct');
            else
                app.TextArea3.Value = strcat(num2str(trueDigits),' out of 50 correct');
            end
            

        end
        
        
    end
   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global net;
        end

        % Button button pushed function
        function TrainButtonPushed(app)
            trainSet = app.DropDown.Value;
            approach = app.DropDown2.Value;
            activationFunction = app.DropDown3.Value;
            
            createNetwork(app,trainSet,approach,activationFunction);
        end

        % Button2 button pushed function
        function TestButtonPushed(app)
                       
            load('testSet.mat');
            load('testSet2.mat');
            load('P.mat');
            load('myPerfect.mat');
            load('T.mat');
            data = load('dataset.mat');
            dataset = data.dataset;
            load('dataset2.mat');
            load('bigDataSet.mat');
            global net;
            
            trainSet = app.DropDown.Value;
            
            if(strcmp(app.DropDown2.Value,'Associative Memory + Classifier'))
                
               if(strcmp(trainSet,'Bad Training Set'))
                   WP = myPerfect * pinv(dataset);
               elseif(strcmp(trainSet,'Good Training Set'))
                   WP = myPerfect * pinv(dataset2);
                else
                   WP = T * pinv(bigDataSet);
               end
           
                intResult = WP * testSet2.P;
                testResults = sim(net,intResult);
                analyzeResults(app,1,testResults);

                intResult = WP * testSet.P;
                testResults = sim(net,intResult);
                analyzeResults(app,2,testResults);
                
                intResult = WP * P;
                testResults = sim(net,intResult);
                analyzeResults(app,3,testResults);
                
            else
                testResults = sim(net,testSet2.P);
                analyzeResults(app,1,testResults);
                
                testResults = sim(net,testSet.P);
                analyzeResults(app,2,testResults);
                
                testResults = sim(net,P);
                analyzeResults(app,3,testResults);
            end
            
        end

        % Button3 button pushed function
        function TestsetButtonPushed(app)
            mpaper;
        end

        % Button4 button pushed function
        function ShowTestButtonPushed(app)
            load('P.mat');
            showim(P);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Neural Network Test Cockpit';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Training';
            app.Panel.Units = 'pixels';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Select Training Set';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Select Approach';
            app.Label4 = uilabel(app.Panel);
            app.Label4.Text = 'Select Activation Function';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed);
            app.Button.Text = 'Train';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Combined Training Set', 'Good Training Set', 'Bad Training Set'};
            app.DropDown.Value = 'Combined Training Set';
            app.LabelDropDown2 = uilabel(app.Panel);
            app.LabelDropDown2.Text = 'Drop Down';
            app.DropDown2 = uidropdown(app.Panel);
            app.DropDown2.Items = {'Associative Memory + Classifier', 'Direct Classifier'};
            app.DropDown2.Value = 'Associative Memory + Classifier';
            app.Label5 = uilabel(app.Panel);
            app.Label5.Text = 'Drop Down';
            app.DropDown3 = uidropdown(app.Panel);
            app.DropDown3.Items = {'Hardlim', 'Purelin', 'Logsig'};
            app.DropDown3.Value = 'Hardlim';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Testset Creation';
            app.Panel2.Units = 'pixels';
            app.Button3 = uibutton(app.Panel2, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @TestsetButtonPushed);
            app.Button3.Text = 'Create Testset';
            app.Button4 = uibutton(app.Panel2, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @ShowTestButtonPushed);
            app.Button4.Text = 'Show Testset';
            app.TextArea4 = uitextarea(app.Panel2);
            app.TextArea4.Editable = 'off';
            app.TextArea4.Value = {'Your own testset '; 'needs to be called '; '"P.mat" and be '; 'located in the same folder of the GUI. It must contain '; 'numbers in each '; 'field (start by 1), so '; 'that we can learn '; 'the weight matrix of the AM.'; 'You can use the '; 'buttons below to '; 'create and show it.'};
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Testing';
            app.Panel3.Units = 'pixels';
            app.Label9 = uilabel(app.Panel3);
            app.Label9.Text = 'Test Results (Own Testset)';
            app.TextArea3 = uitextarea(app.Panel3);
            app.Label8 = uilabel(app.Panel3);
            app.Label8.Text = 'Test Results (Bad Testset)';
            app.TextArea2 = uitextarea(app.Panel3);
            app.LabelTextArea = uilabel(app.Panel3);
            app.LabelTextArea.Text = 'Test Results (Good Testset)';
            app.TextArea = uitextarea(app.Panel3);
            app.Button2 = uibutton(app.Panel3, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed);
            app.Button2.Text = 'Test';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end